classdef record < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        RecordButton  matlab.ui.control.Button
        StopButton    matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
        Image         matlab.ui.control.Image
        Label         matlab.ui.control.Label
        Image2        matlab.ui.control.Image
        Label_2       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Image, RecordButton
        function RecordButtonPushed(app, event)
             global recObj
               recObj = audiorecorder(44100,16, 1) ; %create object
                record(recObj); %start Recording
               app.Label.Text = 'Recording';
        end

        % Callback function: Image2, StopButton
        function StopButtonPushed(app, event)
              global recObj
                stop(recObj) % Stop
                   y=getaudiodata(recObj);
                plot(app.UIAxes,y)
               audiowrite('filename.wav', y,44100);
                app.Label.Text= 'Stop recording';
                 app.Label_2.Text= 'File Saved';
        end

        % Callback function
        function SaveButtonPushed(app, event)
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.Image.ImageSource = 'images.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Image2.ImageSource = 'stop.jpg';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = record

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end